classdef covid_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        MovingAverageDaysLabel       matlab.ui.control.Label
        MovingAverageSpinner         matlab.ui.control.Spinner
        DailyCumulativeGroupButtonGroup  matlab.ui.container.ButtonGroup
        DailyButton                  matlab.ui.control.RadioButton
        CumulativeButton             matlab.ui.control.RadioButton
        CasesDeathsGroupButtonGroup  matlab.ui.container.ButtonGroup
        BothButton                   matlab.ui.control.RadioButton
        DeathsButton                 matlab.ui.control.RadioButton
        CasesButton                  matlab.ui.control.RadioButton
        StatesListBox                matlab.ui.control.ListBox
        StatesListBoxLabel           matlab.ui.control.Label
        CountriesListBox             matlab.ui.control.ListBox
        CountriesListBoxLabel        matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
        ContextMenu                  matlab.ui.container.ContextMenu
        Menu                         matlab.ui.container.Menu
        Menu2                        matlab.ui.container.Menu
    end

    
    properties (Access = private)
    GlobalRegion
end
    
    methods (Access = private)
        
        function startupFcn(app)
            app.CountriesListBox.Items = {'Global','Test1','Test2'};
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn2(app)
load('covid_data.mat')

allCountryNames = covid_data(2:end,1);
uniqueCountryNames = unique(allCountryNames, 'stable');

app.CountriesListBox.Items = ['Global'; uniqueCountryNames];
app.CountriesListBox.Value = 'Global';

app.StatesListBox.Items = {'All'};
app.StatesListBox.Value = 'All';
dateHeaders = covid_data(1,3:end);
dates = datetime(dateHeaders, 'InputFormat', 'M/d/yy');

globalCasesCum = zeros(1,length(dates));
globalDeathsCum = zeros(1,length(dates));

for i = 2:size(covid_data,1)
    rowData = covid_data(i,3:end);
    nDays = numel(rowData);

    casesCum = zeros(1,nDays);
    deathsCum = zeros(1,nDays);

    for k = 1:nDays
        temp = rowData{k};
        casesCum(k) = temp(1);
        deathsCum(k) = temp(2);
    end

    globalCasesCum = globalCasesCum + casesCum;
    globalDeathsCum = globalDeathsCum + deathsCum;
end

app.GlobalRegion = CovidRegion('Global', dates, globalCasesCum, globalDeathsCum);
countryNames = unique(covid_data(2:end,1), 'stable');

for c = 1:length(countryNames)
    currentCountry = countryNames{c};

    casesTotal = zeros(1,length(dates));
    deathsTotal = zeros(1,length(dates));

    for i = 2:size(covid_data,1)
        if strcmp(covid_data{i,1}, currentCountry)

            rowData = covid_data(i,3:end);
            nDays = numel(rowData);

            casesCum = zeros(1,nDays);
            deathsCum = zeros(1,nDays);

            for k = 1:nDays
                temp = rowData{k};
                casesCum(k) = temp(1);
                deathsCum(k) = temp(2);
            end

            casesTotal = casesTotal + casesCum;
            deathsTotal = deathsTotal + deathsCum;
        end
    end

    countryObj = CovidRegion(currentCountry, dates, casesTotal, deathsTotal);
    for i = 2:size(covid_data,1)
    if strcmp(covid_data{i,1}, currentCountry)

        stateName = covid_data{i,2};

        if ~isempty(stateName)

            rowData = covid_data(i,3:end);
            nDays = numel(rowData);

            casesCum = zeros(1,nDays);
            deathsCum = zeros(1,nDays);

            for k = 1:nDays
                temp = rowData{k};
                casesCum(k) = temp(1);
                deathsCum(k) = temp(2);
            end

            stateObj = CovidRegion(stateName, dates, casesCum, deathsCum);
            countryObj = countryObj.addChild(stateObj);
        end
    end
    end

    app.GlobalRegion = app.GlobalRegion.addChild(countryObj);
end
selectedType = app.CasesDeathsGroupButtonGroup.SelectedObject.Text;

cla(app.UIAxes)

if strcmp(selectedType, 'Cases')
    plot(app.UIAxes, app.GlobalRegion.Dates, app.GlobalRegion.CasesCum)
    ylabel(app.UIAxes, 'Cases')

elseif strcmp(selectedType, 'Deaths')
    plot(app.UIAxes, app.GlobalRegion.Dates, app.GlobalRegion.DeathsCum)
    ylabel(app.UIAxes, 'Deaths')

else
    yyaxis(app.UIAxes, 'left')
    plot(app.UIAxes, app.GlobalRegion.Dates, app.GlobalRegion.CasesCum)
    ylabel(app.UIAxes, 'Cases')

    yyaxis(app.UIAxes, 'right')
    plot(app.UIAxes, app.GlobalRegion.Dates, app.GlobalRegion.DeathsCum)
    ylabel(app.UIAxes, 'Deaths')
end

grid(app.UIAxes, 'on')
title(app.UIAxes, 'Global | Cases | Cumulative | 1-day avg')
xlabel(app.UIAxes, 'Date')
app.CountriesListBox.Value = 'Global';
app.StatesListBox.Value = 'All';
        end

        % Value changed function: CountriesListBox
        function CountriesListBoxValueChanged(app, event)
selectedCountry = app.CountriesListBox.Value;


if strcmp(selectedCountry, 'Global')
    app.StatesListBox.Items = {'All'};
    app.StatesListBox.Value = 'All';

    S = load('covid_data.mat');
    data = S.covid_data;

    dateHeaders = data(1,3:end);
    dates = datetime(dateHeaders, 'InputFormat', 'M/d/yy');

   globalCasesCum = zeros(1,length(dates));
    globalDeathsCum = zeros(1,length(dates));

    for i = 2:size(data,1)
        rowData = data(i,3:end);
        nDays = numel(rowData);

        casesCum = zeros(1,nDays);
        deathsCum = zeros(1,nDays);

        for k = 1:nDays
            temp = rowData{k};
            casesCum(k) = temp(1);
            deathsCum(k) = temp(2);
        end

        globalCasesCum = globalCasesCum + casesCum;
        globalDeathsCum = globalDeathsCum + deathsCum;
    end

    selectedType = app.CasesDeathsGroupButtonGroup.SelectedObject.Text;
    selectedMode = app.DailyCumulativeGroupButtonGroup.SelectedObject.Text;
    avgDays = app.MovingAverageSpinner.Value;

    if strcmp(selectedMode, 'Daily')
        casesData = [globalCasesCum(1), diff(globalCasesCum)];
        deathsData = [globalDeathsCum(1), diff(globalDeathsCum)];
        casesData(casesData < 0) = 0;
        deathsData(deathsData < 0) = 0;
    else
        casesData = globalCasesCum;
        deathsData = globalDeathsCum;
    end

    casesData = movmean(casesData, [avgDays-1 0]);
    deathsData = movmean(deathsData, [avgDays-1 0]);

    cla(app.UIAxes)

    if strcmp(selectedType, 'Cases')
        plot(app.UIAxes, dates, casesData)
        ylabel(app.UIAxes, 'Cases')

    elseif strcmp(selectedType, 'Deaths')
        plot(app.UIAxes, dates, deathsData)
        ylabel(app.UIAxes, 'Deaths')

    else
        yyaxis(app.UIAxes, 'left')
        plot(app.UIAxes, dates, casesData)
        ylabel(app.UIAxes, 'Cases')

        yyaxis(app.UIAxes, 'right')
        plot(app.UIAxes, dates, deathsData)
        ylabel(app.UIAxes, 'Deaths')
    end

    grid(app.UIAxes, 'on')
    title(app.UIAxes, ['Global | ' selectedType ' | ' selectedMode ' | ' num2str(avgDays) '-day avg'])
    xlabel(app.UIAxes, 'Date')

    return
end

S = load('covid_data.mat');
data = S.covid_data;

states = {};

for i = 2:size(data,1)
    if strcmp(data{i,1}, selectedCountry)
        stateName = data{i,2};

        if ~isempty(stateName)
            states{end+1} = stateName;
        end
    end
end

if isempty(states)
    app.StatesListBox.Items = {'All'};
else
    app.StatesListBox.Items = [{'All'} states];
end

app.StatesListBox.Value = 'All';

dateHeaders = data(1,3:end);
dates = datetime(dateHeaders, 'InputFormat', 'M/d/yy');

casesTotal = zeros(1,length(dates));
deathsTotal = zeros(1,length(dates));

for i = 2:size(data,1)
    if strcmp(data{i,1}, selectedCountry)
        
        rowData = data(i,3:end);
        nDays = numel(rowData);

        casesCum = zeros(1,nDays);
        deathsCum = zeros(1,nDays);

        for k = 1:nDays
            temp = rowData{k};
            casesCum(k) = temp(1);
            deathsCum(k) = temp(2);
        end

        casesTotal = casesTotal + casesCum;
        deathsTotal = deathsTotal + deathsCum;
    end
end

selectedType = app.CasesDeathsGroupButtonGroup.SelectedObject.Text;
selectedMode = app.DailyCumulativeGroupButtonGroup.SelectedObject.Text;
avgDays = app.MovingAverageSpinner.Value;

if strcmp(selectedMode, 'Daily')
    casesData = [casesTotal(1), diff(casesTotal)];
    deathsData = [deathsTotal(1), diff(deathsTotal)];
    casesData(casesData < 0) = 0;
    deathsData(deathsData < 0) = 0;
else
    casesData = casesTotal;
    deathsData = deathsTotal;
end

casesData = movmean(casesData, [avgDays-1 0]);
deathsData = movmean(deathsData, [avgDays-1 0]);

cla(app.UIAxes)

if strcmp(selectedType, 'Cases')
    plot(app.UIAxes, dates, casesData)
    ylabel(app.UIAxes, 'Cases')

elseif strcmp(selectedType, 'Deaths')
    plot(app.UIAxes, dates, deathsData)
    ylabel(app.UIAxes, 'Deaths')

else
    yyaxis(app.UIAxes, 'left')
    plot(app.UIAxes, dates, casesData)
    ylabel(app.UIAxes, 'Cases')

    yyaxis(app.UIAxes, 'right')
    plot(app.UIAxes, dates, deathsData)
    ylabel(app.UIAxes, 'Deaths')
end

grid(app.UIAxes, 'on')
title(app.UIAxes, [selectedCountry ' | ' selectedType ' | ' selectedMode ' | ' num2str(avgDays) '-day avg'])
xlabel(app.UIAxes, 'Date')
        end

        % Value changed function: StatesListBox
        function StatesListBoxValueChanged(app, event)
                 selectedCountry = app.CountriesListBox.Value;
    selectedState = app.StatesListBox.Value;

    S = load('covid_data.mat');
    data = S.covid_data;

    dateHeaders = data(1,3:end);
    dates = datetime(dateHeaders, 'InputFormat', 'M/d/yy');

    selectedType = app.CasesDeathsGroupButtonGroup.SelectedObject.Text;
    selectedMode = app.DailyCumulativeGroupButtonGroup.SelectedObject.Text;

    if strcmp(selectedCountry, 'Global')
        totalData = zeros(1,length(dates));

        for i = 2:size(data,1)
            rowData = data(i,3:end);
            nDays = numel(rowData);
            values = zeros(1,nDays);

            for k = 1:nDays
                temp = rowData{k};

                if strcmp(selectedType, 'Cases')
                        value = temp(1);
                elseif strcmp(selectedType, 'Deaths')
                        value = temp(2);
                else
                        value = temp(1);
end

                values(k) = value;
            end

            totalData = totalData + values;
        end

        casesData = totalData;
deathsData = totalData;

if strcmp(selectedMode, 'Daily')
    casesData = [casesData(1), diff(casesData)];
    deathsData = [deathsData(1), diff(deathsData)];
    casesData(casesData < 0) = 0;
    deathsData(deathsData < 0) = 0;
end

cla(app.UIAxes)

if strcmp(selectedType, 'Cases')
    plot(app.UIAxes, dates, casesData)
    ylabel(app.UIAxes, 'Cases')

elseif strcmp(selectedType, 'Deaths')
    plot(app.UIAxes, dates, deathsData)
    ylabel(app.UIAxes, 'Deaths')

else
    yyaxis(app.UIAxes, 'left')
    plot(app.UIAxes, dates, casesData)
    ylabel(app.UIAxes, 'Cases')

    yyaxis(app.UIAxes, 'right')
    plot(app.UIAxes, dates, deathsData)
    ylabel(app.UIAxes, 'Deaths')
end

if strcmp(selectedState, 'All')
    plotTitle = [selectedCountry ' ' selectedMode];
else
    plotTitle = [selectedCountry ' - ' selectedState ' ' selectedMode];
end

grid(app.UIAxes, 'on')
if strcmp(selectedState, 'All')
    title(app.UIAxes, [selectedCountry ' | ' selectedType ' | ' selectedMode])
else
    title(app.UIAxes, [selectedCountry ' - ' selectedState ' | ' selectedType ' | ' selectedMode])
end
xlabel(app.UIAxes, 'Date')
        return
    end

    totalData = zeros(1,length(dates));

    for i = 2:size(data,1)
        countryMatch = strcmp(data{i,1}, selectedCountry);

        if strcmp(selectedState, 'All')
            stateMatch = true;
        else
            stateMatch = strcmp(data{i,2}, selectedState);
        end

        if countryMatch && stateMatch
            rowData = data(i,3:end);
            nDays = numel(rowData);
            values = zeros(1,nDays);

            for k = 1:nDays
                temp = rowData{k};

                if strcmp(selectedType, 'Cases')
                        value = temp(1);
                elseif strcmp(selectedType, 'Deaths')
                        value = temp(2);
                else
                        value = temp(1);
                            end

                values(k) = value;
            end

            totalData = totalData + values;
        end
    end

    if strcmp(selectedMode, 'Daily')
        plotData = [totalData(1), diff(totalData)];
        plotData(plotData < 0) = 0;
    else
        plotData = totalData;
    end

    if strcmp(selectedState, 'All')
        plotTitle = [selectedCountry ' ' selectedMode ' ' selectedType];
    else
        plotTitle = [selectedCountry ' - ' selectedState ' ' selectedMode ' ' selectedType];
    end

    plot(app.UIAxes, dates, plotData)
    grid(app.UIAxes, 'on')
    title(app.UIAxes, plotTitle)
    xlabel(app.UIAxes, 'Date')
    ylabel(app.UIAxes, selectedType)

            
        end

        % Callback function
        function CasesDeathsGroupButtonGroupSelectionChanged(app, event)
            selectedButton = app.CasesDeathsGroupButtonGroup.SelectedObject;
            
        end

        % Selection changed function: CasesDeathsGroupButtonGroup
        function CasesDeathsGroupButtonGroupSelectionChanged2(app, event)
            CountriesListBoxValueChanged(app, []);
                        
        end

        % Selection changed function: DailyCumulativeGroupButtonGroup
        function DailyCumulativeGroupButtonGroupSelectionChanged(app, event)
            selectedButton = app.DailyCumulativeGroupButtonGroup.SelectedObject;
            if strcmp(app.StatesListBox.Value, 'All')
                CountriesListBoxValueChanged(app, []);
            else
                    StatesListBoxValueChanged(app, []);
            end
        end

        % Value changed function: MovingAverageSpinner
        function MovingAverageSpinnerValueChanged(app, event)
            if strcmp(app.StatesListBox.Value, 'All')
                CountriesListBoxValueChanged(app, []);
            else
                    StatesListBoxValueChanged(app, []);
                end
        
    
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.CountriesListBoxLabel = uilabel(app.UIFigure);
            app.CountriesListBoxLabel.Text = 'Countries';
            app.CountriesListBox = uilistbox(app.UIFigure);
            app.CountriesListBox.ValueChangedFcn = createCallbackFcn(app, @CountriesListBoxValueChanged, true);
            app.StatesListBoxLabel = uilabel(app.UIFigure);
            app.StatesListBoxLabel.Text = 'States';
            app.StatesListBox = uilistbox(app.UIFigure);
            app.StatesListBox.ValueChangedFcn = createCallbackFcn(app, @StatesListBoxValueChanged, true);
            app.CasesDeathsGroupButtonGroup = uibuttongroup(app.UIFigure);
            app.CasesDeathsGroupButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CasesDeathsGroupButtonGroupSelectionChanged2, true);
            app.CasesDeathsGroupButtonGroup.Title = 'CasesDeathsGroup';
            app.CasesButton = uiradiobutton(app.CasesDeathsGroupButtonGroup);
            app.CasesButton.Text = 'Cases';
            app.CasesButton.Value = true;
            app.DeathsButton = uiradiobutton(app.CasesDeathsGroupButtonGroup);
            app.DeathsButton.Text = 'Deaths';
            app.BothButton = uiradiobutton(app.CasesDeathsGroupButtonGroup);
            app.BothButton.Text = 'Both';
            app.DailyCumulativeGroupButtonGroup = uibuttongroup(app.UIFigure);
            app.DailyCumulativeGroupButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DailyCumulativeGroupButtonGroupSelectionChanged, true);
            app.DailyCumulativeGroupButtonGroup.Title = 'DailyCumulativeGroup';
            app.CumulativeButton = uiradiobutton(app.DailyCumulativeGroupButtonGroup);
            app.CumulativeButton.Text = 'Cumulative';
            app.CumulativeButton.Value = true;
            app.DailyButton = uiradiobutton(app.DailyCumulativeGroupButtonGroup);
            app.DailyButton.Text = 'Daily';
            app.MovingAverageSpinner = uispinner(app.UIFigure);
            app.MovingAverageSpinner.Limits = [1 15];
            app.MovingAverageSpinner.ValueChangedFcn = createCallbackFcn(app, @MovingAverageSpinnerValueChanged, true);
            app.MovingAverageSpinner.Value = 1;
            app.MovingAverageDaysLabel = uilabel(app.UIFigure);
            app.MovingAverageDaysLabel.Text = ' Moving Average Days';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Assign app.ContextMenu
            app.CasesDeathsGroupButtonGroup.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = covid_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn2)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end